classdef SuperformulaGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        AxPlot             matlab.ui.control.UIAxes
        ParametersLabel    matlab.ui.control.Label
        LobesSpinnerLabel  matlab.ui.control.Label
        SelectM            matlab.ui.control.Spinner
        n1SliderLabel      matlab.ui.control.Label
        SelectN1           matlab.ui.control.Slider
        n2SliderLabel      matlab.ui.control.Label
        SelectN2           matlab.ui.control.Slider
        n3SliderLabel      matlab.ui.control.Label
        SelectN3           matlab.ui.control.Slider
        a1Label            matlab.ui.control.Label
        SelectA1           matlab.ui.control.Slider
        a2Label            matlab.ui.control.Label
        SelectA2           matlab.ui.control.Slider
        SupeformulaLabel   matlab.ui.control.Label
    end

    
    % Attribution
    % 25/05/2019
    % Ivan Alvarez
    % FMRIB Centre, University of Oxford 
    
    % Values to share across functions
    properties (Access = private)
        M
        N1
        N2
        N3
        A1
        A2
        Grain
    end
    
    % Functions
    methods (Access = private)
        
        % Update plot
        function updateplot(app)
            
            % Inputs
            m = app.M;
            n = [app.N1, app.N2, app.N3];
            a = [app.A1, app.A2];
            grain = app.Grain;
            
            % Generate points
            s = Superformula(m, n, a, grain);
            
            % Plot
            h = surf(app.AxPlot, s.X, s.Y, s.Z);
            
            % Tidy
            h.EdgeColor = 'none';
            h.FaceColor = [1 0.8 0.8];
            app.AxPlot.Visible = 'off';
            pbaspect(app.AxPlot, [1, 1, 1]);
            light(app.AxPlot);
            
            % Parameters as text
            app.ParametersLabel.Text = ...
                [num2str(m, '%0.2f'), '   ', ...
                num2str(n(1), '%0.2f'), '   ', ...
                num2str(n(2), '%0.2f'), '   ', ...
                num2str(n(3), '%0.2f')];
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            % Values inherited from GUI
            app.M = app.SelectM.Value;
            app.N1 = app.SelectN1.Value;
            app.N2 = app.SelectN2.Value;
            app.N3 = app.SelectN3.Value;
            app.A1 = app.SelectA1.Value;
            app.A2 = app.SelectA2.Value;
            
            % Hard-coded values
            app.Grain = 100;
            
            % Update plot
            updateplot(app);
        end

        % Value changed function: SelectM
        function ChangeM(app, event)
            app.M = app.SelectM.Value;
            updateplot(app);
        end

        % Value changed function: SelectN1
        function ChangeN1(app, event)
            app.N1 = app.SelectN1.Value;
            updateplot(app);
        end

        % Value changed function: SelectN2
        function ChangeN2(app, event)
            app.N2 = app.SelectN2.Value;
            updateplot(app);
        end

        % Value changed function: SelectN3
        function ChangeN3(app, event)
            app.N3 = app.SelectN3.Value;
            updateplot(app);
        end

        % Value changed function: SelectA1
        function ChangeA1(app, event)
            app.A1 = app.SelectA1.Value;
            updateplot(app);
        end

        % Value changed function: SelectA2
        function ChangeA2(app, event)
            app.A2 = app.SelectA2.Value;
            updateplot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AxPlot = uiaxes(app.UIFigure);
            xlabel(app.AxPlot, '')
            app.AxPlot.XTick = [];
            app.AxPlot.YTick = [];
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = '6   5   6   9';
            app.LobesSpinnerLabel = uilabel(app.UIFigure);
            app.LobesSpinnerLabel.Text = 'Lobes';
            app.SelectM = uispinner(app.UIFigure);
            app.SelectM.Limits = [1 20];
            app.SelectM.ValueChangedFcn = createCallbackFcn(app, @ChangeM, true);
            app.SelectM.Value = 6;
            app.n1SliderLabel = uilabel(app.UIFigure);
            app.n1SliderLabel.Text = 'n1';
            app.SelectN1 = uislider(app.UIFigure);
            app.SelectN1.Limits = [0 12];
            app.SelectN1.ValueChangedFcn = createCallbackFcn(app, @ChangeN1, true);
            app.SelectN1.Value = 5;
            app.n2SliderLabel = uilabel(app.UIFigure);
            app.n2SliderLabel.Text = 'n2';
            app.SelectN2 = uislider(app.UIFigure);
            app.SelectN2.Limits = [0 12];
            app.SelectN2.ValueChangedFcn = createCallbackFcn(app, @ChangeN2, true);
            app.SelectN2.Value = 6;
            app.n3SliderLabel = uilabel(app.UIFigure);
            app.n3SliderLabel.Text = 'n3';
            app.SelectN3 = uislider(app.UIFigure);
            app.SelectN3.Limits = [0 12];
            app.SelectN3.ValueChangedFcn = createCallbackFcn(app, @ChangeN3, true);
            app.SelectN3.Value = 9;
            app.a1Label = uilabel(app.UIFigure);
            app.a1Label.Text = 'a1';
            app.SelectA1 = uislider(app.UIFigure);
            app.SelectA1.Limits = [0 3];
            app.SelectA1.MajorTicks = [0 1 2 3];
            app.SelectA1.ValueChangedFcn = createCallbackFcn(app, @ChangeA1, true);
            app.SelectA1.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3];
            app.SelectA1.Value = 1;
            app.a2Label = uilabel(app.UIFigure);
            app.a2Label.Text = 'a2';
            app.SelectA2 = uislider(app.UIFigure);
            app.SelectA2.Limits = [0 3];
            app.SelectA2.MajorTicks = [0 1 2 3];
            app.SelectA2.ValueChangedFcn = createCallbackFcn(app, @ChangeA2, true);
            app.SelectA2.Value = 1;
            app.SupeformulaLabel = uilabel(app.UIFigure);
            app.SupeformulaLabel.Text = 'Supeformula';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SuperformulaGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end